classdef SignalAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        signal_status                  matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        OriginalSampledTab             matlab.ui.container.Tab
        CrossCorrelationPanel_2        matlab.ui.container.Panel
        CrossCorrelationAxis           matlab.ui.control.UIAxes
        OriginalECGSignalPanel         matlab.ui.container.Panel
        OriginalSignalAxis             matlab.ui.control.UIAxes
        SampledSignalPanel             matlab.ui.container.Panel
        ResampledDataAxis              matlab.ui.control.UIAxes
        NoiseFilteredTab               matlab.ui.container.Tab
        IIRFilteredOutputPanel         matlab.ui.container.Panel
        UIAxes5_5                      matlab.ui.control.UIAxes
        FIRFilteredOutputPanel         matlab.ui.container.Panel
        UIAxes5_4                      matlab.ui.control.UIAxes
        NoisySignalMultipleNoiseSourcePanel  matlab.ui.container.Panel
        NoiseSignalAxis                matlab.ui.control.UIAxes
        FilterComparisonTab            matlab.ui.container.Tab
        FIRvsIIRFilterPerformanceComparisonTImeDomainPanel  matlab.ui.container.Panel
        UIAxes4_4                      matlab.ui.control.UIAxes
        FIRvsIIRFilterMagnitudeResponsePanel  matlab.ui.container.Panel
        UIAxes4_3                      matlab.ui.control.UIAxes
        SpectrumTab                    matlab.ui.container.Tab
        FilteredSignalPowerSpectrumPanel  matlab.ui.container.Panel
        UIAxes5_7                      matlab.ui.control.UIAxes
        OriginalSignalPowerSpectrumPanel  matlab.ui.container.Panel
        UIAxes5_6                      matlab.ui.control.UIAxes
        SpectrogramPanel               matlab.ui.container.Panel
        UIAxes4_5                      matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        AcquisitionTab                 matlab.ui.container.Tab
        DisplayOptionPanel             matlab.ui.container.Panel
        ChannelSpinner                 matlab.ui.control.Spinner
        ChannelSpinnerLabel            matlab.ui.control.Label
        TimeWindowHighSpinner          matlab.ui.control.Spinner
        TimeWindowHighSpinnerLabel     matlab.ui.control.Label
        TimeWindowLowSpinner           matlab.ui.control.Spinner
        TimeWindowLowSpinnerLabel      matlab.ui.control.Label
        SliceDataButton                matlab.ui.control.Button
        SignalInformationPanel         matlab.ui.container.Panel
        signal_information_label       matlab.ui.control.Label
        SignalSourcePanel              matlab.ui.container.Panel
        ImportDigitizedDataButton      matlab.ui.control.Button
        LoadPhysioNetDatabaseButton    matlab.ui.control.Button
        file_chosen_or_not             matlab.ui.control.Label
        ChooseFileButton               matlab.ui.control.Button
        ProcessingTab                  matlab.ui.container.Tab
        NoiseAdditionPanel             matlab.ui.container.Panel
        NoiseTypeDropDown              matlab.ui.control.DropDown
        NoiseTypeDropDownLabel         matlab.ui.control.Label
        AddNoiseButton                 matlab.ui.control.Button
        SNRLeveldBSlider               matlab.ui.control.Slider
        SNRLeveldBSliderLabel          matlab.ui.control.Label
        CrossCorrelationPanel          matlab.ui.container.Panel
        CorrelationStatusLabel         matlab.ui.control.Label
        ComputeCorrelationButton       matlab.ui.control.Button
        SamplingParametersPanel        matlab.ui.container.Panel
        SamplingFrequencySpinner       matlab.ui.control.Spinner
        SamplingFrequencySpinnerLabel  matlab.ui.control.Label
        OriginalFrequencyLabel         matlab.ui.control.Label
        ResampleSignalButton           matlab.ui.control.Button
        FilteringTab                   matlab.ui.container.Tab
        FilterComparisonPanel          matlab.ui.container.Panel
        ShowFrequencyResponseButton    matlab.ui.control.Button
        CompareFIRvsIIRFilterButton    matlab.ui.control.Button
        IIRFiterDesignPanel            matlab.ui.container.Panel
        ApplyIIRFilterButton           matlab.ui.control.Button
        CutoffFreqHzSpinner_2          matlab.ui.control.Spinner
        CutoffFreqHzSpinner_2Label     matlab.ui.control.Label
        FilterOrderSpinner_2           matlab.ui.control.Spinner
        FilterOrderSpinner_2Label      matlab.ui.control.Label
        FilterTypeDropDown_2           matlab.ui.control.DropDown
        FilterTypeDropDown_2Label      matlab.ui.control.Label
        FIRFiterDesignPanel            matlab.ui.container.Panel
        ApplyFIRFilterButton           matlab.ui.control.Button
        CutoffFreqHzSpinner            matlab.ui.control.Spinner
        CutoffFreqHzLabel              matlab.ui.control.Label
        FilterOrderSpinner             matlab.ui.control.Spinner
        FilterOrderSpinnerLabel        matlab.ui.control.Label
        FilterTypeDropDown             matlab.ui.control.DropDown
        FilterTypeLabel                matlab.ui.control.Label
        AnalysisTab                    matlab.ui.container.Tab
        ExportReportPanel              matlab.ui.container.Panel
        SaveAllPlotsButton             matlab.ui.control.Button
        ExportDataButton               matlab.ui.control.Button
        GenerateReportButton           matlab.ui.control.Button
        SpectralAnalysisPanel          matlab.ui.container.Panel
        SpectrogramButton              matlab.ui.control.Button
        PowerSpectrumFFTButton         matlab.ui.control.Button
        SignalQualityMetricesPanel     matlab.ui.container.Panel
        SNRdBMSEPRDLabel               matlab.ui.control.Label
        PRDCalculationButton           matlab.ui.control.Button
        CalculateSNRButton_2           matlab.ui.control.Button
        CalculateSNRButton             matlab.ui.control.Button
        BiomedicalSignalProcessingToolLabel  matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

      
   properties (Access = private)
        buffer ECGBuffer    % Now you'll get autocomplete!
        status struct
        loaded=false
        windowed_data
        windowed_time
        windowed_data_resampled
        windowed_time_resampled
        active_channel=1
        windowd_data_noisy
        opts = struct('baselineWander',true, 'gaussian',true, ...
              'highFreq',true, 'powerLine',true, 'emg',true);
   end

    methods (Access = private)
      
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function on_ready(app)
            
        end

        % Button pushed function: ChooseFileButton
        function on_choose_file_button_pressed(app, event)
           [temp_buffer, app.status] = load_physionet_gui('preview', false);
            if app.status.success
                % Convert to ECGBuffer object
                app.buffer = ECGBuffer();
                app.buffer.data = temp_buffer.data;
                app.buffer.time = temp_buffer.time;
                app.buffer.fs = temp_buffer.fs;
                app.buffer.channels = temp_buffer.channels;
                app.buffer.units = temp_buffer.units;
                app.buffer.gain = temp_buffer.gain;
                app.buffer.baseline = temp_buffer.baseline;
                app.buffer.filepath = temp_buffer.filepath;
                app.buffer.record = temp_buffer.record;
                app.buffer.loaded = temp_buffer.loaded;
                app.buffer.meta = temp_buffer.meta;
                
                app.signal_information_label.Text = app.status.summary;
                
                % fix the sliders and ui
                app.signal_status.Text= 'Signal Loaded Sucessfully';
                app.file_chosen_or_not.Text= app.buffer.filepath;
                app.OriginalFrequencyLabel.Text= ['Original Frequency:',num2str(app.buffer.fs),' Hz'];
                
                app.loaded=true;
                

            else
                warndlg(app.status.message, 'Loading Error');
                app.signal_status.Text= 'Ready| No Signal Loaded | Awaiting user Input';
                app.file_chosen_or_not.Text= "No File Chosen";
            end


        end

        % Button pushed function: SliceDataButton
        function on_slice_data_button_pressed(app, event)
            if app.loaded    
                
                tlow= app.TimeWindowLowSpinner.Value   ;
                thigh= app.TimeWindowHighSpinner.Value  ;
                if thigh <=tlow
                    warndlg('Time window low is higher')
                end

                idx = (app.buffer.time >= tlow) & (app.buffer.time <=thigh);
                app.windowed_data= app.buffer.data(idx,:);
                app.windowed_time= app.buffer.time(idx);

                channel= app.ChannelSpinner.Value;
                try 
                    plot(app.OriginalSignalAxis, app.windowed_time, app.windowed_data(:, channel));
                    % Correct way to set title and labels
                    title(app.OriginalSignalAxis, 'Original ECG Signal');
                    xlabel(app.OriginalSignalAxis, 'Time (s)');
                    ylabel(app.OriginalSignalAxis, ['Voltage ' char(app.buffer.units)]);
                    app.active_channel= channel;
                catch exception
                    warndlg("That channel doesn't exist,\nUsing Channel 1",'Warning');
                    plot(app.OriginalSignalAxis, app.windowed_time, app.windowed_data(:,1));
            
                    % Set labels again
                    title(app.OriginalSignalAxis, 'Original ECG Signal');
                    xlabel(app.OriginalSignalAxis, 'Time (s)');
                    ylabel(app.OriginalSignalAxis, ['Voltage ' char(app.buffer.units)]);
                    app.active_channel=1;
                end
            end

        end

        % Button pushed function: ResampleSignalButton
        function on_resample_pressed(app, event)

            if ~app.loaded
                warndlg("Data not loaded.\n Load Data First.", 'Warning!');
                return;
            end
            
            % === Retrieve original data and resampling settings ===
            old_data_all = app.windowed_data;  % All channels
            old_time = app.windowed_time;
            Fs_old = app.buffer.fs;
            Fs_new = app.SamplingFrequencySpinner.Value;
            
            % === Resample ===
            t_start = old_time(1);
            t_end = old_time(end);
            duration = t_end - t_start;
            N_new = round(duration * Fs_new) + 1; % number of samples in new frequency
            
            % Create new time vector
            app.windowed_time_resampled = linspace(t_start, t_end, N_new)';  % Column vector
            
            % Resample ALL channels
            num_channels = size(old_data_all, 2);
            app.windowed_data_resampled = zeros(N_new, num_channels);
            
            for ch = 1:num_channels
                app.windowed_data_resampled(:, ch) = interp1(old_time, old_data_all(:, ch), ...
                    app.windowed_time_resampled, 'linear');
            end
            
            % === Calculate Match Quality Metrics for ACTIVE channel ===
            old_data = old_data_all(:, app.active_channel);
            
            % Resample new signal back to original time base for comparison
            y_on_old_time = interp1(app.windowed_time_resampled, ...
                app.windowed_data_resampled(:, app.active_channel), old_time, 'linear', 'extrap');
            
            % Ensure column vectors
            x = old_data(:);
            y = y_on_old_time(:);
            
            % Remove any NaN values from extrapolation
            valid_idx = ~isnan(y);
            x = x(valid_idx);
            y = y(valid_idx);
            
            % Check if we have valid data
            if length(x) < 2
                warndlg('Insufficient valid data for quality metrics.', 'Warning!');
                return;
            end
            
            % Correlation
            R = corrcoef(x, y);
            if numel(R) > 1
                corr_val = R(1,2);
            else
                corr_val = 0;
            end
            
            % RMSE and NRMSE
            rmse = sqrt(mean((x - y).^2));
            data_range = max(x) - min(x);
            if data_range == 0
                nrmse = 0;
            else
                nrmse = rmse / data_range;
            end
            
            % Signal-to-noise ratio
            signal_power = mean(x.^2);
            noise_power = mean((x - y).^2);
            
            if noise_power == 0 || signal_power == 0
                snr_db = Inf;
            else
                snr_db = 10 * log10(signal_power / noise_power);
            end
            
            % Composite Quality Index (0–1)
            snr_normalized = 1 / (1 + exp(-snr_db / 10));
            Q = (corr_val + (1 - nrmse) + snr_normalized) / 3;
            
            % === Plotting (active channel only) ===
            cla(app.ResampledDataAxis);
            hold(app.ResampledDataAxis, 'on');
            
            plot(app.ResampledDataAxis, old_time, old_data, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Original');
            plot(app.ResampledDataAxis, app.windowed_time_resampled, ...
                app.windowed_data_resampled(:, app.active_channel), 'r--', 'LineWidth', 1.5, 'DisplayName', 'Resampled');
            
            legend(app.ResampledDataAxis, 'Location', 'best');
            title(app.ResampledDataAxis, sprintf('Channel %d: Original (%.0f Hz) vs Resampled (%.0f Hz)', ...
                app.active_channel, Fs_old, Fs_new));
            xlabel(app.ResampledDataAxis, 'Time (s)');
            ylabel(app.ResampledDataAxis, ['Voltage ' char(app.buffer.units)]);
            grid(app.ResampledDataAxis, 'on');
            hold(app.ResampledDataAxis, 'off');
            
            % === Update Status Label ===
            app.CorrelationStatusLabel.Text = sprintf([ ...
                'Channel %d | %.2f Hz → %.2f Hz\n' ...
                'Correlation: %.4f\n' ...
                'NRMSE: %.4f\n' ...
                'SNR: %.2f dB\n' ...
                'Quality Index: %.4f'], ...
                app.active_channel, Fs_old, Fs_new, corr_val, nrmse, snr_db, Q);


        end

        % Button pushed function: ComputeCorrelationButton
        function on_compute_correlation_button_pressed(app, event)

           if ~app.loaded
                warndlg("Data not loaded.\n Load Data First.", 'Warning!');
                return;
            end
            
            % Get signals for active channel
            x = app.windowed_data(:, app.active_channel);
            y = app.windowed_data_resampled(:, app.active_channel);
            
            % Get time vectors
            t_original = app.windowed_time;
            t_resampled = app.windowed_time_resampled;
            
            % Debug: Check dimensions
            fprintf('Original signal length: %d, time length: %d\n', length(x), length(t_original));
            fprintf('Resampled signal length: %d, time length: %d\n', length(y), length(t_resampled));
            
            % Ensure time vectors match signal lengths
            if length(t_original) ~= length(x)
                % Regenerate original time vector
                Fs_orig = 1 / mean(diff(app.windowed_time));
                t_original = (0:length(x)-1)' / Fs_orig + app.windowed_time(1);
            end
            
            if length(t_resampled) ~= length(y)
                % Regenerate resampled time vector
                Fs_resamp = 1 / mean(diff(app.windowed_time_resampled));
                t_resampled = (0:length(y)-1)' / Fs_resamp + app.windowed_time_resampled(1);
            end
            
            % Ensure column vectors
            x = x(:);
            y = y(:);
            t_original = t_original(:);
            t_resampled = t_resampled(:);
            
            % Resample y to match x's time base
            try
                y_aligned = interp1(t_resampled, y, t_original, 'linear', 'extrap');
            catch ME
                warndlg(sprintf('Interpolation failed: %s\nUsing truncated correlation instead.', ME.message), 'Warning!');
                
                % Fallback: truncate to common length
                N = min(length(x), length(y));
                x_clean = x(1:N);
                y_clean = y(1:N);
                Fs_original = 1 / mean(diff(t_original));
            end
            
            % If interpolation succeeded
            if exist('y_aligned', 'var')
                % Remove any NaN values
                valid_idx = ~isnan(y_aligned);
                x_clean = x(valid_idx);
                y_clean = y_aligned(valid_idx);
                Fs_original = 1 / mean(diff(t_original));
            end
            
            % Check if we have valid data
            if length(x_clean) < 2 || length(y_clean) < 2
                warndlg('Insufficient valid data points for cross-correlation.', 'Warning!');
                return;
            end
            
            % Compute normalized cross-correlation
            [xc, lags] = xcorr(x_clean, y_clean, 'coeff');
            
            % Convert lags to time
            lag_time = lags / Fs_original;
            
            % Find peak correlation and corresponding lag
            [max_corr, max_idx] = max(xc);
            peak_lag = lag_time(max_idx);
            
            % Find zero-lag correlation
            [~, zero_idx] = min(abs(lags));
            zero_corr = xc(zero_idx);
            
            % Plot cross-correlation
            cla(app.CrossCorrelationAxis);
            plot(app.CrossCorrelationAxis, lag_time, xc, 'b-', 'LineWidth', 1.5);
            hold(app.CrossCorrelationAxis, 'on');
            
            % Mark peak correlation
            plot(app.CrossCorrelationAxis, peak_lag, max_corr, 'ro', ...
                'MarkerSize', 8, 'MarkerFaceColor', 'r');
            
            % Mark zero-lag correlation
            plot(app.CrossCorrelationAxis, 0, zero_corr, 'go', ...
                'MarkerSize', 8, 'MarkerFaceColor', 'g');
            
            % Add zero-lag vertical line
            yl = [-1 1];
            plot(app.CrossCorrelationAxis, [0 0], yl, 'k--', 'LineWidth', 1);
            
            hold(app.CrossCorrelationAxis, 'off');
            
            % Labels and formatting
            xlabel(app.CrossCorrelationAxis, 'Lag (s)');
            ylabel(app.CrossCorrelationAxis, 'Correlation Coefficient');
            title(app.CrossCorrelationAxis, sprintf('Cross-Correlation | Zero-lag: %.3f | Max: %.3f at %.3fs', ...
                zero_corr, max_corr, peak_lag));
            grid(app.CrossCorrelationAxis, 'on');
            xlim(app.CrossCorrelationAxis, [min(lag_time) max(lag_time)]);
            ylim(app.CrossCorrelationAxis, [-1 1]);
            
            % Legend
            legend(app.CrossCorrelationAxis, 'Cross-Correlation', ...
                sprintf('Max (%.3f)', max_corr), sprintf('Zero-lag (%.3f)', zero_corr), ...
                'Location', 'best');

        end

        % Button pushed function: AddNoiseButton
        function on_add_noise_button_pressed(app, event)
               % === Check data availability ===
            if isempty(app.windowed_data_resampled)
                warndlg("No resampled data found. Please load or process data first.", 'Warning!');
                return;
            end
        
            % === Extract selected channel ===
            sig = app.windowed_data_resampled(:, app.active_channel);
        
            % === Retrieve parameters ===
            fs = app.SamplingFrequencySpinner.Value;
            targetSNR = app.SNRLeveldBSlider.Value;
        
            % === Add noise ===
            app.windowd_data_noisy = addNoiseToDataset(sig, fs, targetSNR, app.opts);
        
            % === Plot results ===
            % === Plot results ===
            cla(app.NoiseSignalAxis);
            plot(app.NoiseSignalAxis, app.windowed_time_resampled, app.windowd_data_noisy, 'r');
            hold(app.NoiseSignalAxis, 'on');
            plot(app.NoiseSignalAxis, app.windowed_time_resampled, sig, 'b');
            hold(app.NoiseSignalAxis, 'off');
            legend(app.NoiseSignalAxis, {'Noisy Signal','Original Signal'});
            title(app.NoiseSignalAxis, sprintf('Signal with Added Noise (SNR = %.1f dB)', targetSNR));
            xlabel(app.NoiseSignalAxis, 'Time (s)');
            ylabel(app.NoiseSignalAxis, 'Amplitude');
            
            % === Nyquist warning check ===
            nyquist = fs / 2;
            maxNoiseFreq = 200; % e.g. EMG or power line upper limit
            
            if nyquist < maxNoiseFreq
                warningText = sprintf('⚠ Nyquist limit (%.1f Hz) < noise freq (%.1f Hz)\nNoise may not appear correctly.', nyquist, maxNoiseFreq);
                
                % Show text warning on the plot
                text(app.NoiseSignalAxis, ...
                    0.05*max(app.windowed_time_resampled), ... % x position
                    0.9*max(sig), ...                          % y position
                    warningText, ...
                    'Color', 'r', 'FontSize', 10, 'FontWeight', 'bold', ...
                    'BackgroundColor', [1 1 0.8], 'EdgeColor', [1 0.5 0], ...
                    'Margin', 5, 'Interpreter', 'none');
            end
       

        end

        % Button pushed function: ApplyFIRFilterButton
        function on_apply_fir_filter_button_pressed(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BiomedicalSignalProcessingToolLabel = uilabel(app.UIFigure);
            app.BiomedicalSignalProcessingToolLabel.Text = 'Biomedical Signal Processing Tool';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.SignalSourcePanel = uipanel(app.AcquisitionTab);
            app.SignalSourcePanel.Title = 'Signal Source';
            app.ChooseFileButton = uibutton(app.SignalSourcePanel, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @on_choose_file_button_pressed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.file_chosen_or_not = uilabel(app.SignalSourcePanel);
            app.file_chosen_or_not.Text = 'No File Chosen';
            app.LoadPhysioNetDatabaseButton = uibutton(app.SignalSourcePanel, 'push');
            app.LoadPhysioNetDatabaseButton.Text = 'Load PhysioNet Database';
            app.ImportDigitizedDataButton = uibutton(app.SignalSourcePanel, 'push');
            app.ImportDigitizedDataButton.Text = 'Import Digitized Data';
            app.SignalInformationPanel = uipanel(app.AcquisitionTab);
            app.SignalInformationPanel.Title = 'Signal Information';
            app.signal_information_label = uilabel(app.SignalInformationPanel);
            app.signal_information_label.Text = '';
            app.DisplayOptionPanel = uipanel(app.AcquisitionTab);
            app.DisplayOptionPanel.Title = 'Display Option';
            app.SliceDataButton = uibutton(app.DisplayOptionPanel, 'push');
            app.SliceDataButton.ButtonPushedFcn = createCallbackFcn(app, @on_slice_data_button_pressed, true);
            app.SliceDataButton.Text = 'Slice Data';
            app.TimeWindowLowSpinnerLabel = uilabel(app.DisplayOptionPanel);
            app.TimeWindowLowSpinnerLabel.Text = 'Time Window Low';
            app.TimeWindowLowSpinner = uispinner(app.DisplayOptionPanel);
            app.TimeWindowHighSpinnerLabel = uilabel(app.DisplayOptionPanel);
            app.TimeWindowHighSpinnerLabel.Text = 'Time Window High';
            app.TimeWindowHighSpinner = uispinner(app.DisplayOptionPanel);
            app.TimeWindowHighSpinner.Value = 100;
            app.ChannelSpinnerLabel = uilabel(app.DisplayOptionPanel);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.DisplayOptionPanel);
            app.ChannelSpinner.Value = 1;
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.SamplingParametersPanel = uipanel(app.ProcessingTab);
            app.SamplingParametersPanel.Title = 'Sampling Parameters';
            app.ResampleSignalButton = uibutton(app.SamplingParametersPanel, 'push');
            app.ResampleSignalButton.ButtonPushedFcn = createCallbackFcn(app, @on_resample_pressed, true);
            app.ResampleSignalButton.Text = 'Resample Signal';
            app.OriginalFrequencyLabel = uilabel(app.SamplingParametersPanel);
            app.OriginalFrequencyLabel.Text = 'Original Frequency: ';
            app.SamplingFrequencySpinnerLabel = uilabel(app.SamplingParametersPanel);
            app.SamplingFrequencySpinnerLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencySpinner = uispinner(app.SamplingParametersPanel);
            app.SamplingFrequencySpinner.Limits = [0 10000];
            app.SamplingFrequencySpinner.Value = 10;
            app.CrossCorrelationPanel = uipanel(app.ProcessingTab);
            app.CrossCorrelationPanel.Title = 'Cross-Correlation';
            app.ComputeCorrelationButton = uibutton(app.CrossCorrelationPanel, 'push');
            app.ComputeCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @on_compute_correlation_button_pressed, true);
            app.ComputeCorrelationButton.Text = 'Compute Correlation';
            app.CorrelationStatusLabel = uilabel(app.CrossCorrelationPanel);
            app.CorrelationStatusLabel.Text = '';
            app.NoiseAdditionPanel = uipanel(app.ProcessingTab);
            app.NoiseAdditionPanel.Title = 'Noise Addition';
            app.SNRLeveldBSliderLabel = uilabel(app.NoiseAdditionPanel);
            app.SNRLeveldBSliderLabel.Text = 'SNR Level(dB)';
            app.SNRLeveldBSlider = uislider(app.NoiseAdditionPanel);
            app.AddNoiseButton = uibutton(app.NoiseAdditionPanel, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @on_add_noise_button_pressed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.NoiseTypeDropDownLabel = uilabel(app.NoiseAdditionPanel);
            app.NoiseTypeDropDownLabel.Text = 'Noise Type';
            app.NoiseTypeDropDown = uidropdown(app.NoiseAdditionPanel);
            app.NoiseTypeDropDown.Items = {'Baseline Wander', 'Gaussian Noise', 'High Frequency Overshoot', 'Power Line', 'EMG Interface'};
            app.NoiseTypeDropDown.Value = 'Baseline Wander';
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.FIRFiterDesignPanel = uipanel(app.FilteringTab);
            app.FIRFiterDesignPanel.Title = 'FIR Fiter Design';
            app.FilterTypeLabel = uilabel(app.FIRFiterDesignPanel);
            app.FilterTypeLabel.Text = 'Filter Type:';
            app.FilterTypeDropDown = uidropdown(app.FIRFiterDesignPanel);
            app.FilterTypeDropDown.Items = {'Lowpass', 'Highpass', 'Bandpass', 'Bandstop', 'Kalman', 'Moving Average', 'Moving Exponential'};
            app.FilterTypeDropDown.Value = 'Lowpass';
            app.FilterOrderSpinnerLabel = uilabel(app.FIRFiterDesignPanel);
            app.FilterOrderSpinnerLabel.Text = 'Filter Order:';
            app.FilterOrderSpinner = uispinner(app.FIRFiterDesignPanel);
            app.FilterOrderSpinner.Value = 50;
            app.CutoffFreqHzLabel = uilabel(app.FIRFiterDesignPanel);
            app.CutoffFreqHzLabel.Text = 'Cutoff Freq(Hz):';
            app.CutoffFreqHzSpinner = uispinner(app.FIRFiterDesignPanel);
            app.CutoffFreqHzSpinner.Value = 40;
            app.ApplyFIRFilterButton = uibutton(app.FIRFiterDesignPanel, 'push');
            app.ApplyFIRFilterButton.ButtonPushedFcn = createCallbackFcn(app, @on_apply_fir_filter_button_pressed, true);
            app.ApplyFIRFilterButton.Text = 'Apply FIR Filter';
            app.IIRFiterDesignPanel = uipanel(app.FilteringTab);
            app.IIRFiterDesignPanel.Title = 'IIR Fiter Design';
            app.FilterTypeDropDown_2Label = uilabel(app.IIRFiterDesignPanel);
            app.FilterTypeDropDown_2Label.Text = 'Filter Type:';
            app.FilterTypeDropDown_2 = uidropdown(app.IIRFiterDesignPanel);
            app.FilterTypeDropDown_2.Items = {'Butterworth', 'Chebyshev Type1', 'Chebyshev Type2', 'Elliptic'};
            app.FilterTypeDropDown_2.Value = 'Butterworth';
            app.FilterOrderSpinner_2Label = uilabel(app.IIRFiterDesignPanel);
            app.FilterOrderSpinner_2Label.Text = 'Filter Order:';
            app.FilterOrderSpinner_2 = uispinner(app.IIRFiterDesignPanel);
            app.FilterOrderSpinner_2.Value = 4;
            app.CutoffFreqHzSpinner_2Label = uilabel(app.IIRFiterDesignPanel);
            app.CutoffFreqHzSpinner_2Label.Text = 'Cutoff Freq(Hz):';
            app.CutoffFreqHzSpinner_2 = uispinner(app.IIRFiterDesignPanel);
            app.CutoffFreqHzSpinner_2.Value = 40;
            app.ApplyIIRFilterButton = uibutton(app.IIRFiterDesignPanel, 'push');
            app.ApplyIIRFilterButton.Text = 'Apply IIR Filter';
            app.FilterComparisonPanel = uipanel(app.FilteringTab);
            app.FilterComparisonPanel.Title = 'Filter Comparison';
            app.CompareFIRvsIIRFilterButton = uibutton(app.FilterComparisonPanel, 'push');
            app.CompareFIRvsIIRFilterButton.Text = 'Compare FIR vs IIR Filter';
            app.ShowFrequencyResponseButton = uibutton(app.FilterComparisonPanel, 'push');
            app.ShowFrequencyResponseButton.Text = 'Show Frequency Response';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.SignalQualityMetricesPanel = uipanel(app.AnalysisTab);
            app.SignalQualityMetricesPanel.Title = 'Signal Quality Metrices';
            app.CalculateSNRButton = uibutton(app.SignalQualityMetricesPanel, 'push');
            app.CalculateSNRButton.Text = 'Calculate SNR';
            app.CalculateSNRButton_2 = uibutton(app.SignalQualityMetricesPanel, 'push');
            app.CalculateSNRButton_2.Text = 'Calculate SNR';
            app.PRDCalculationButton = uibutton(app.SignalQualityMetricesPanel, 'push');
            app.PRDCalculationButton.Text = 'PRD Calculation';
            app.SNRdBMSEPRDLabel = uilabel(app.SignalQualityMetricesPanel);
            app.SNRdBMSEPRDLabel.Text = {'SNR (dB):                                                     --'; 'MSE:                                                             --'; 'PRD (%):                                                      --'};
            app.SpectralAnalysisPanel = uipanel(app.AnalysisTab);
            app.SpectralAnalysisPanel.Title = 'Spectral Analysis';
            app.PowerSpectrumFFTButton = uibutton(app.SpectralAnalysisPanel, 'push');
            app.PowerSpectrumFFTButton.Text = 'Power Spectrum (FFT)';
            app.SpectrogramButton = uibutton(app.SpectralAnalysisPanel, 'push');
            app.SpectrogramButton.Text = 'Spectrogram';
            app.ExportReportPanel = uipanel(app.AnalysisTab);
            app.ExportReportPanel.Title = 'Export & Report';
            app.GenerateReportButton = uibutton(app.ExportReportPanel, 'push');
            app.GenerateReportButton.Text = 'Generate Report';
            app.ExportDataButton = uibutton(app.ExportReportPanel, 'push');
            app.ExportDataButton.Text = 'Export Data';
            app.SaveAllPlotsButton = uibutton(app.ExportReportPanel, 'push');
            app.SaveAllPlotsButton.Text = 'Save All Plots';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.OriginalSampledTab = uitab(app.TabGroup2);
            app.OriginalSampledTab.Title = 'Original & Sampled';
            app.SampledSignalPanel = uipanel(app.OriginalSampledTab);
            app.SampledSignalPanel.Title = 'Sampled Signal';
            app.ResampledDataAxis = uiaxes(app.SampledSignalPanel);
            title(app.ResampledDataAxis, 'Title')
            xlabel(app.ResampledDataAxis, 'X')
            ylabel(app.ResampledDataAxis, 'Y')
            zlabel(app.ResampledDataAxis, 'Z')
            app.OriginalECGSignalPanel = uipanel(app.OriginalSampledTab);
            app.OriginalECGSignalPanel.Title = 'Original ECG Signal';
            app.OriginalSignalAxis = uiaxes(app.OriginalECGSignalPanel);
            title(app.OriginalSignalAxis, 'Title')
            xlabel(app.OriginalSignalAxis, 'X')
            ylabel(app.OriginalSignalAxis, 'Y')
            zlabel(app.OriginalSignalAxis, 'Z')
            app.CrossCorrelationPanel_2 = uipanel(app.OriginalSampledTab);
            app.CrossCorrelationPanel_2.Title = 'Cross Correlation';
            app.CrossCorrelationAxis = uiaxes(app.CrossCorrelationPanel_2);
            title(app.CrossCorrelationAxis, 'Title')
            xlabel(app.CrossCorrelationAxis, 'X')
            ylabel(app.CrossCorrelationAxis, 'Y')
            zlabel(app.CrossCorrelationAxis, 'Z')
            app.NoiseFilteredTab = uitab(app.TabGroup2);
            app.NoiseFilteredTab.Title = 'Noise & Filtered';
            app.NoisySignalMultipleNoiseSourcePanel = uipanel(app.NoiseFilteredTab);
            app.NoisySignalMultipleNoiseSourcePanel.Title = 'Noisy Signal (Multiple Noise Source)';
            app.NoiseSignalAxis = uiaxes(app.NoisySignalMultipleNoiseSourcePanel);
            title(app.NoiseSignalAxis, 'Title')
            xlabel(app.NoiseSignalAxis, 'X')
            ylabel(app.NoiseSignalAxis, 'Y')
            zlabel(app.NoiseSignalAxis, 'Z')
            app.FIRFilteredOutputPanel = uipanel(app.NoiseFilteredTab);
            app.FIRFilteredOutputPanel.Title = 'FIR Filtered Output';
            app.UIAxes5_4 = uiaxes(app.FIRFilteredOutputPanel);
            title(app.UIAxes5_4, 'Title')
            xlabel(app.UIAxes5_4, 'X')
            ylabel(app.UIAxes5_4, 'Y')
            zlabel(app.UIAxes5_4, 'Z')
            app.IIRFilteredOutputPanel = uipanel(app.NoiseFilteredTab);
            app.IIRFilteredOutputPanel.Title = 'IIR Filtered Output';
            app.UIAxes5_5 = uiaxes(app.IIRFilteredOutputPanel);
            title(app.UIAxes5_5, 'Title')
            xlabel(app.UIAxes5_5, 'X')
            ylabel(app.UIAxes5_5, 'Y')
            zlabel(app.UIAxes5_5, 'Z')
            app.FilterComparisonTab = uitab(app.TabGroup2);
            app.FilterComparisonTab.Title = 'Filter Comparison';
            app.FIRvsIIRFilterMagnitudeResponsePanel = uipanel(app.FilterComparisonTab);
            app.FIRvsIIRFilterMagnitudeResponsePanel.Title = 'FIR vs IIR Filter - Magnitude Response';
            app.UIAxes4_3 = uiaxes(app.FIRvsIIRFilterMagnitudeResponsePanel);
            title(app.UIAxes4_3, 'Title')
            xlabel(app.UIAxes4_3, 'X')
            ylabel(app.UIAxes4_3, 'Y')
            zlabel(app.UIAxes4_3, 'Z')
            app.FIRvsIIRFilterPerformanceComparisonTImeDomainPanel = uipanel(app.FilterComparisonTab);
            app.FIRvsIIRFilterPerformanceComparisonTImeDomainPanel.Title = 'FIR vs IIR Filter - Performance Comparison (TIme Domain)';
            app.UIAxes4_4 = uiaxes(app.FIRvsIIRFilterPerformanceComparisonTImeDomainPanel);
            title(app.UIAxes4_4, 'Title')
            xlabel(app.UIAxes4_4, 'X')
            ylabel(app.UIAxes4_4, 'Y')
            zlabel(app.UIAxes4_4, 'Z')
            app.SpectrumTab = uitab(app.TabGroup2);
            app.SpectrumTab.Title = 'Spectrum';
            app.SpectrogramPanel = uipanel(app.SpectrumTab);
            app.SpectrogramPanel.Title = 'Spectrogram';
            app.UIAxes4_5 = uiaxes(app.SpectrogramPanel);
            title(app.UIAxes4_5, 'Title')
            xlabel(app.UIAxes4_5, 'X')
            ylabel(app.UIAxes4_5, 'Y')
            zlabel(app.UIAxes4_5, 'Z')
            app.OriginalSignalPowerSpectrumPanel = uipanel(app.SpectrumTab);
            app.OriginalSignalPowerSpectrumPanel.Title = 'Original Signal- Power Spectrum';
            app.UIAxes5_6 = uiaxes(app.OriginalSignalPowerSpectrumPanel);
            title(app.UIAxes5_6, 'Title')
            xlabel(app.UIAxes5_6, 'X')
            ylabel(app.UIAxes5_6, 'Y')
            zlabel(app.UIAxes5_6, 'Z')
            app.FilteredSignalPowerSpectrumPanel = uipanel(app.SpectrumTab);
            app.FilteredSignalPowerSpectrumPanel.Title = 'Filtered Signal Power Spectrum';
            app.UIAxes5_7 = uiaxes(app.FilteredSignalPowerSpectrumPanel);
            title(app.UIAxes5_7, 'Title')
            xlabel(app.UIAxes5_7, 'X')
            ylabel(app.UIAxes5_7, 'Y')
            zlabel(app.UIAxes5_7, 'Z')
            app.signal_status = uilabel(app.UIFigure);
            app.signal_status.Text = 'Ready| No Signal Loaded | Awaiting user Input';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @on_ready)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end